classdef PPGFeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGFeatUIFigure             matlab.ui.Figure
        fEditField                  matlab.ui.control.NumericEditField
        fEditFieldLabel             matlab.ui.control.Label
        SkipSsqiCheckBox            matlab.ui.control.CheckBox
        EnterSamplingfreqfilterfreqLabel  matlab.ui.control.Label
        FHEditField                 matlab.ui.control.NumericEditField
        FHEditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        F1Label                     matlab.ui.control.Label
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        DataTextArea                matlab.ui.control.TextArea
        DataTextAreaLabel           matlab.ui.control.Label
        PlotpressedLamp             matlab.ui.control.Lamp
        PlotpressedLampLabel        matlab.ui.control.Label
        APGFiducialPointsLabel      matlab.ui.control.Label
        VPGFiducialPointsLabel      matlab.ui.control.Label
        PPGFiducialPointsLabel      matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        GotonextwaveformLabel       matlab.ui.control.Label
        UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel  matlab.ui.control.Label
        Plotallwaveformsusingmin1andmin2Label  matlab.ui.control.Label
        Yes0NoLabel                 matlab.ui.control.Label
        canddpresentEditField       matlab.ui.control.NumericEditField
        canddpresentEditFieldLabel  matlab.ui.control.Label
        StandardAPGFeaturesLabel    matlab.ui.control.Label
        StandardVPGFeaturesLabel    matlab.ui.control.Label
        StandardPPGFeaturesLabel    matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        dEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cmLabel                     matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        zEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel             matlab.ui.control.Label
        wEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel             matlab.ui.control.Label
        DEditField                  matlab.ui.control.NumericEditField
        DEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        SEditField                  matlab.ui.control.NumericEditField
        SEditFieldLabel             matlab.ui.control.Label
        OEditField                  matlab.ui.control.NumericEditField
        OEditFieldLabel             matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        SsqiEditField               matlab.ui.control.NumericEditField
        SsqiEditFieldLabel          matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        SkipforbadSsqiButton        matlab.ui.control.Button
        unfit                       matlab.ui.control.Lamp
        acceptable                  matlab.ui.control.Lamp
        good                        matlab.ui.control.Lamp
        PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel  matlab.ui.control.Label
        SQI082Good03SQI081AcceptableSQI03UnfitLabel  matlab.ui.control.Label
        GenerateoutputButton        matlab.ui.control.Button
        IDEditField                 matlab.ui.control.NumericEditField
        IDLabel                     matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Min2EditField               matlab.ui.control.NumericEditField
        Min2EditFieldLabel          matlab.ui.control.Label
        Min1EditField               matlab.ui.control.NumericEditField
        Image4                      matlab.ui.control.Image
        Min1EditFieldLabel          matlab.ui.control.Label
        CSVwithSsqianddataIDLabel   matlab.ui.control.Label
        LoadSsqiButton              matlab.ui.control.Button
        RAWPPGdataLabel             matlab.ui.control.Label
        LoadPPGButton               matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        Image5                      matlab.ui.control.Image
        Image6                      matlab.ui.control.Image
        Label_4                     matlab.ui.control.Label
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PPGAnalObject
    end

    methods (Access = private)

        function UpdateSsqi(app)
            app.SsqiEditField.Value = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,1);
            app.PPGAnalObject.Sub_ID = app.PPGAnalObject.Ssqi(app.PPGAnalObject.next,2);
            app.IDEditField.Value = app.PPGAnalObject.Sub_ID;

            if app.SsqiEditField.Value > 0.8
                app.Lamp.Color = 'g';
            end

            if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
                app.Lamp.Color = 'y';

            end

            if app.SsqiEditField.Value < 0.3
                app.Lamp.Color = 'r';
            end

            app.PlotpressedLamp.Color = 'r';
        end

        function PlotPPG(app)
            app.UpdateSsqi();

            plot(app.UIAxes4, app.PPGAnalObject.loadedData(app.PPGAnalObject.next,:))  %Plot first RAW waveform

            %show data numbr in the text area 
            Dat1 = num2str(app.PPGAnalObject.Size_loaded_data(1));
            Dat2 = num2str(app.PPGAnalObject.next);
            Dat = Dat2 + " of " + Dat1;
            app.DataTextArea.Value = Dat;

            %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.next ,:);
            PPG = plot(app.UIAxes, filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes, "on")

                        %find maxima and minima of current segment
            PPG_max = islocalmax(filtered_data ,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",200,"MaxNumExtrema",10);
            PPG_min = islocalmin(filtered_data ,"MinProminence",0.1,"FlatSelection","all",...
                "MinSeparation",100,"MaxNumExtrema",10);

            index_PPG_max = find(PPG_max); %index of maxima points
            value_PPG_max = filtered_data(PPG_max);  %value at maxima point

            index_PPG_min = find( PPG_min) ; %index of maxima points
            value_PPG_min = filtered_data( PPG_min) ;  %value at maxima point


            if (~isempty(index_PPG_max))
                %ax = gca;
                %chart = ax.Children();
                for ii = 1:length(index_PPG_max)
                    datatip(PPG,index_PPG_max(ii),value_PPG_max(ii),'FontSize',8);
                end
            end


            if (~isempty(index_PPG_min))
%                 ax = gca;
%                 chart = ax.Children();
                for ij = 1:length(index_PPG_min)
                    datatip(PPG,index_PPG_min(ij),value_PPG_min(ij),'FontSize',8,'Location','southeast');
                end
            end

            %read min1 and min from ppg waveform and show in the edit field

            % Set the threshold value for comparing the minima indices
            threshold = 40 * app.PPGAnalObject.Fs/100;
            
            % Loop over all minima indices
            for i = 1:length(index_PPG_min)-1
                
                % Check if the next minimum index is greater than the current one by
                % the threshold value
                if index_PPG_min(i+1) > index_PPG_min(i) + threshold
                    % If yes, check if there is any maximum index between the two minima
                    maxima_between_minima = index_PPG_max(index_PPG_max > index_PPG_min(i) & index_PPG_max < index_PPG_min(i+1));
                    if ~isempty(maxima_between_minima)
                        
                        % Add any other code you need to perform here
                    app.Min1EditField.Value = index_PPG_min(i);
                    app.Min2EditField.Value = index_PPG_min(i+1);
                    break
                    end
                end
            end
        end
        
        function PlotFiducial(app, PPGAnal)
            %plot the PPG segment
            P_seg = plot(app.UIAxes2,PPGAnal.seg);
            grid(app.UIAxes2,"on")

            line(app.UIAxes2,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:4
                datatip(P_seg, PPGAnal.OSND_time(i),PPGAnal.OSND(i),'FontSize',8);
            end

            %plot VPG
            P_vpg = plot(app.UIAxes6, PPGAnal.VPG);
            grid(app.UIAxes6,"on")

            line(app.UIAxes6,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:4
                datatip(P_vpg, PPGAnal.WXYZ_time(i),PPGAnal.WXYZ(i),'FontSize',8);
            end

            %plot APG
            P_apg = plot(app.UIAxes5, PPGAnal.APG);
            grid(app.UIAxes5,"on")

            line(app.UIAxes5,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment    
        
            for i = 1:5
                datatip(P_apg, PPGAnal.abcde_time(i),PPGAnal.abcde(i),'FontSize',8);
            end
            datatip(P_apg, PPGAnal.f, PPGAnal.APG(PPGAnal.f),'FontSize',8);
        
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PPGAnalObject = ppg_anal;
        end

        % Button pushed function: LoadPPGButton
        function LoadPPGButtonPushed(app, event)

            app.PPGAnalObject.LoadPPG(app.FsHzEditField.Value, app.FLEditField.Value, app.FHEditField.Value);

            % Create expty Ssqi values
            A = ones(app.PPGAnalObject.Size_loaded_data(1),1);
            B = 1:1:app.PPGAnalObject.Size_loaded_data(1);
            B = B';
            app.PPGAnalObject.Ssqi = [A B];

            app.PlotPPG();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            app.PlotpressedLamp.Color = 'g'; %to show user that the plot button has not been pressed

            PPGAnal.CalculateFiducial(app.Min1EditField.Value, app.Min2EditField.Value);

            %Update fields after with ppg_anal values
            app.OEditField.Value = PPGAnal.O;
            app.SEditField.Value = PPGAnal.S;
            app.NEditField.Value = PPGAnal.N;
            app.DEditField.Value = PPGAnal.D;

            app.wEditField.Value = PPGAnal.W;
            app.xEditField.Value = PPGAnal.X;
            app.yEditField.Value = PPGAnal.Y;
            app.zEditField.Value = PPGAnal.Z;

            app.aEditField.Value = PPGAnal.a;
            app.bEditField.Value = PPGAnal.b;
            app.cEditField.Value = PPGAnal.c;
            app.dEditField.Value = PPGAnal.d;
            app.eEditField.Value = PPGAnal.e;
            app.fEditField.Value = PPGAnal.f;

            app.canddpresentEditField.Value = PPGAnal.c_and_d_present;

            app.PlotFiducial(PPGAnal);

            %plot overall PPG segments
            hold(app.UIAxes3,"on")
            plot(app.UIAxes3, PPGAnal.seg)
            hold(app.UIAxes3,"off")
        end

        % Button pushed function: GenerateoutputButton
        function GenerateoutputButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            app.PPGAnalObject.next = app.PPGAnalObject.next+1;  %increase the counter for next value

            %alert on 2nd last data
            if (app.PPGAnalObject.next+1 == app.PPGAnalObject.Size_loaded_data(1))
            msgbox("This is second last data","Alert","warn");
            end

            %autosave onlast data
            if (app.PPGAnalObject.next > app.PPGAnalObject.Size_loaded_data(1))
            msgbox("Auto saving","Alert","warn");
            GenerateoutputButtonPushed(app, event)
            ExitButtonPushed(app, event)
            end

            app.PlotPPG();
        end

        % Button pushed function: LoadSsqiButton
        function LoadSsqiButtonPushed(app, event)
            app.PPGAnalObject.LoadSsqi();
            
            app.UpdateSsqi();
        end

        % Button pushed function: SkipforbadSsqiButton
        function SkipforbadSsqiButtonPushed(app, event)
            NextButtonPushed(app, event);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            %%Value update for PPG
            PPGAnal.OSND = [PPGAnal.seg(app.OEditField.Value) PPGAnal.seg(app.SEditField.Value) PPGAnal.seg(app.NEditField.Value) PPGAnal.seg(app.DEditField.Value)];

            %Value update for VPG
            PPGAnal.WXYZ = [PPGAnal.VPG(app.wEditField.Value) PPGAnal.VPG(app.xEditField.Value) PPGAnal.VPG(app.yEditField.Value) PPGAnal.VPG(app.zEditField.Value)];

            %value update for APG
            PPGAnal.c_d_APG(PPGAnal.next) = app.canddpresentEditField.Value;
            PPGAnal.abcde = [PPGAnal.APG(app.aEditField.Value) PPGAnal.APG(app.bEditField.Value) PPGAnal.APG(app.cEditField.Value) PPGAnal.APG(app.dEditField.Value) PPGAnal.APG(app.eEditField.Value)];
            
            %time variables of all waveform
            PPGAnal.OSND_time  = [app.OEditField.Value app.SEditField.Value app.NEditField.Value app.DEditField.Value];
            PPGAnal.WXYZ_time  = [app.wEditField.Value app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            PPGAnal.abcde_time = [app.aEditField.Value app.bEditField.Value app.cEditField.Value app.dEditField.Value app.eEditField.Value];
            
            %for f values of APG
            F_Value = PPGAnal.APG(app.fEditField.Value);
            F_t = app.fEditField.Value;
            %for On+1 values of PPG
            O_next_t = ((app.Min2EditField.Value - app.Min1EditField.Value) + 16);
            %disp(O_next)
            O_next = PPGAnal.seg(O_next_t);
            %feature table
            PPGAnal.feature(PPGAnal.next,:) = [PPGAnal.OSND O_next PPGAnal.WXYZ PPGAnal.abcde F_Value PPGAnal.OSND_time O_next_t PPGAnal.WXYZ_time PPGAnal.abcde_time F_t];
            %disp(app.feature)

            %update the figues
            app.PlotFiducial(PPGAnal);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.PPGFeatUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PPGFeatUIFigure = uifigure('Visible', 'off');
            app.PPGFeatUIFigure.Name = 'PPGFeat';
            app.UIAxes = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes, 'Filtered PPG')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Normalized values')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes2, 'PPG Segment')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes3, 'PPG Segments')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Normalized Values')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes4, 'RAW PPG')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes5, 'APG')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes6, 'VPG')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Label_4 = uilabel(app.PPGFeatUIFigure);
            app.Label_4.Text = ' ';
            app.Image6 = uiimage(app.PPGFeatUIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'APG-ref.png');
            app.Image5 = uiimage(app.PPGFeatUIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'VPG-ref.png');
            app.Label_3 = uilabel(app.PPGFeatUIFigure);
            app.Label_3.Text = ' ';
            app.LoadPPGButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.LoadPPGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPPGButtonPushed, true);
            app.LoadPPGButton.Text = 'Load PPG';
            app.RAWPPGdataLabel = uilabel(app.PPGFeatUIFigure);
            app.RAWPPGdataLabel.Text = 'RAW PPG data';
            app.LoadSsqiButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.LoadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSsqiButtonPushed, true);
            app.LoadSsqiButton.Text = 'Load Ssqi';
            app.CSVwithSsqianddataIDLabel = uilabel(app.PPGFeatUIFigure);
            app.CSVwithSsqianddataIDLabel.Text = 'CSV with Ssqi and data ID';
            app.Min1EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min1EditFieldLabel.Text = 'Min1';
            app.Image4 = uiimage(app.PPGFeatUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'PPG-ref.png');
            app.Min1EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Min2EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min2EditFieldLabel.Text = 'Min2';
            app.Min2EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.PlotButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NextButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.IDLabel = uilabel(app.PPGFeatUIFigure);
            app.IDLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.GenerateoutputButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.GenerateoutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateoutputButtonPushed, true);
            app.GenerateoutputButton.Text = 'Generate output';
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel = uilabel(app.PPGFeatUIFigure);
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel.Text = {'SQI > 0.82       = Good'; '0.3<SQI<0.81  = Acceptable'; 'SQI <0.3          = Unfit'};
            app.PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel = uilabel(app.PPGFeatUIFigure);
            app.PPGsegmentAPGsegmentFeaturesSegmentlocationsLabel.Text = {'PPG segment'; 'APG segment'; 'Features'; 'Segment locations'};
            app.good = uilamp(app.PPGFeatUIFigure);
            app.acceptable = uilamp(app.PPGFeatUIFigure);
            app.unfit = uilamp(app.PPGFeatUIFigure);
            app.SkipforbadSsqiButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.SkipforbadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @SkipforbadSsqiButtonPushed, true);
            app.SkipforbadSsqiButton.Text = 'Skip for bad Ssqi';
            app.UpdateButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SsqiEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.SsqiEditFieldLabel.Text = 'Ssqi';
            app.SsqiEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Label = uilabel(app.PPGFeatUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.PPGFeatUIFigure);
            app.OEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.OEditFieldLabel.Text = 'O';
            app.OEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.SEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.DEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.wEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.cmLabel = uilabel(app.PPGFeatUIFigure);
            app.cmLabel.Text = 'c ';
            app.cEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.dEditFieldLabel.Text = 'd ';
            app.dEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.eEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.StandardPPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardPPGFeaturesLabel.Text = 'Standard PPG Features';
            app.StandardVPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardVPGFeaturesLabel.Text = 'Standard VPG Features';
            app.StandardAPGFeaturesLabel = uilabel(app.PPGFeatUIFigure);
            app.StandardAPGFeaturesLabel.Text = 'Standard APG Features';
            app.canddpresentEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.canddpresentEditFieldLabel.Text = 'c and d present ';
            app.canddpresentEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Yes0NoLabel = uilabel(app.PPGFeatUIFigure);
            app.Yes0NoLabel.Text = '1: Yes, 0: No';
            app.Plotallwaveformsusingmin1andmin2Label = uilabel(app.PPGFeatUIFigure);
            app.Plotallwaveformsusingmin1andmin2Label.Text = {'Plot all waveforms using '; 'min1 and min2'};
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel = uilabel(app.PPGFeatUIFigure);
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel.Text = {'Update feature points using'; 'modified user defined values'};
            app.GotonextwaveformLabel = uilabel(app.PPGFeatUIFigure);
            app.GotonextwaveformLabel.Text = 'Go to next waveform ';
            app.Label_2 = uilabel(app.PPGFeatUIFigure);
            app.Label_2.Text = 'PPG Filtering, Segmentation & Fiducial Point Extraction Toolbox';
            app.PPGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.PPGFiducialPointsLabel.Text = 'PPG Fiducial Points';
            app.VPGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.VPGFiducialPointsLabel.Text = 'VPG Fiducial Points';
            app.APGFiducialPointsLabel = uilabel(app.PPGFeatUIFigure);
            app.APGFiducialPointsLabel.Text = 'APG Fiducial Points';
            app.PlotpressedLampLabel = uilabel(app.PPGFeatUIFigure);
            app.PlotpressedLampLabel.Text = 'Plot pressed';
            app.PlotpressedLamp = uilamp(app.PPGFeatUIFigure);
            app.DataTextAreaLabel = uilabel(app.PPGFeatUIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.PPGFeatUIFigure);
            app.ExitButton = uibutton(app.PPGFeatUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label_5 = uilabel(app.PPGFeatUIFigure);
            app.Label_5.Text = '';
            app.FsHzEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.FsHzEditFieldLabel.Text = 'Fs  (Hz)';
            app.FsHzEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FsHzEditField.Value = 1000;
            app.F1Label = uilabel(app.PPGFeatUIFigure);
            app.F1Label.Text = 'FL';
            app.FLEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FLEditField.Value = 0.4;
            app.FHEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.FHEditField.Value = 8;
            app.EnterSamplingfreqfilterfreqLabel = uilabel(app.PPGFeatUIFigure);
            app.EnterSamplingfreqfilterfreqLabel.Text = '  Enter Sampling freq & filter freq';
            app.SkipSsqiCheckBox = uicheckbox(app.PPGFeatUIFigure);
            app.SkipSsqiCheckBox.Text = 'Skip Ssqi';
            app.SkipSsqiCheckBox.Value = true;
            app.fEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            % Show the figure after all components are created
            app.PPGFeatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGFeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGFeatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGFeatUIFigure)
        end
    end
end